classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Phi_cEditField                 matlab.ui.control.NumericEditField
        Phi_cEditFieldLabel            matlab.ui.control.Label
        Alpha_cEditField               matlab.ui.control.NumericEditField
        Alpha_cEditFieldLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        TempsdesimulationsSlider       matlab.ui.control.Slider
        TempsdesimulationsSliderLabel  matlab.ui.control.Label
        DistancexducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistancexducodebarrescmEditFieldLabel  matlab.ui.control.Label
        DistanceyducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistanceyducodebarrescmLabel   matlab.ui.control.Label
        DmarerlasimulationButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DmarerlasimulationButton
        function DmarerlasimulationButtonPushed(app, event)
            assignin("base", "dx_c",app.DistancexducodebarrescmEditField.Value);
            assignin("base", "dy_c",app.DistanceyducodebarrescmEditField.Value);
            assignin("base", "alpha_c",app.Alpha_cEditField.Value);
            assignin("base", "phi_c",app.Phi_cEditField.Value);
            code=sim("Frame.slx", "StopTime",num2str(app.TempsdesimulationsSlider.Value));
            code=transpose(code);
            code=code(1100:1530);
            for i=1:(100)
            code=[code;code(1,:)];
            end
            saveas(imshow(code), "code.png")
            I = imread("code.png");
            msg = readBarcode(I);
            disp(msg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DmarerlasimulationButton = uibutton(app.UIFigure, 'push');
            app.DmarerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarerlasimulationButtonPushed, true);
            app.DmarerlasimulationButton.Text = 'Démarer la simulation';
            app.DistanceyducodebarrescmLabel = uilabel(app.UIFigure);
            app.DistanceyducodebarrescmLabel.Text = 'Distance y du code-barres (cm)';
            app.DistanceyducodebarrescmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancexducodebarrescmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancexducodebarrescmEditFieldLabel.Text = 'Distance x du code-barres (cm)';
            app.DistancexducodebarrescmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempsdesimulationsSliderLabel = uilabel(app.UIFigure);
            app.TempsdesimulationsSliderLabel.Text = 'Temps de simulation(s)';
            app.TempsdesimulationsSlider = uislider(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ULAVAL.png';
            app.Alpha_cEditFieldLabel = uilabel(app.UIFigure);
            app.Alpha_cEditFieldLabel.Text = 'Alpha_c';
            app.Alpha_cEditField = uieditfield(app.UIFigure, 'numeric');
            app.Phi_cEditFieldLabel = uilabel(app.UIFigure);
            app.Phi_cEditFieldLabel.Text = 'Phi_c';
            app.Phi_cEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end